classdef assignment_1618007 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InitiateButton                 matlab.ui.control.Button
        increasecontrastButton         matlab.ui.control.Button
        showmontageofdataButton        matlab.ui.control.Button
        DataloadedLampLabel            matlab.ui.control.Label
        DataloadedLamp                 matlab.ui.control.Lamp
        ChoosecutEditFieldLabel        matlab.ui.control.Label
        ChoosecutEditField             matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        DisplayBrainMassButton         matlab.ui.control.Button
        EndpositionofbrainmassEditFieldLabel  matlab.ui.control.Label
        EndpositionofbrainmassEditField  matlab.ui.control.NumericEditField
        LowertheresholdEditFieldLabel  matlab.ui.control.Label
        LowertheresholdEditField       matlab.ui.control.NumericEditField
        UpperTheresholdEditFieldLabel  matlab.ui.control.Label
        UpperTheresholdEditField       matlab.ui.control.NumericEditField
        OpenImageButton                matlab.ui.control.Button
        FindBlobsButton                matlab.ui.control.Button
        KeeplargestblobButton          matlab.ui.control.Button
        GreyandWhitematterSeparationButton  matlab.ui.control.Button
        IsosurfaceButton               matlab.ui.control.Button
        nhoodXEditFieldLabel           matlab.ui.control.Label
        nhoodXEditField                matlab.ui.control.NumericEditField
        nhoodYEditFieldLabel           matlab.ui.control.Label
        nhoodYEditField                matlab.ui.control.NumericEditField
        PleasespecifymatlabfolderwithDICOMfilesfirstLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MRI % Description
        Cut_number=30; % Description
        upper=0; % Default value set
        lower=0; % Default value set
        X_val=0; % Default value set
        Y_val=0; % Default value set
        MRI_u % Description
        bw_temp % Description
        info_data % Description
        stats_data % Description
        L_data=0; % Description
        Partition % Description
        vox % Description
        flag=0; % before initialization
        end_p % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitiateButton
        function InitiateButtonPushed(app, event)
            
            selpath = uigetdir(path)
            fileFolder=fullfile(selpath, 'Series 8');
            files=dir(fullfile(fileFolder, '*.dcm'));
            fileNames={files.name};
            info=dicominfo(fullfile(fileFolder, fileNames{1}));
            app.info_data=info;
            % Extract size data from metadata
            voxel_size= [info.PixelSpacing; info.SliceThickness]';
            app.vox=voxel_size;
            %Get file information
            numImages=length(fileNames);
            hWaitBar= waitbar(0,'Reading DICOM files');
            mri=zeros(info.Rows, info.Columns, numImages);
            for i=length(fileNames):-1:1
                fname=fullfile(fileFolder, fileNames{i});
                mri(:,:,i)=uint16(dicomread(fname));
                waitbar((length(fileNames)-i+1)/length(fileNames))
            end
            app.MRI=mri;
            delete(hWaitBar)
            app.flag=1;
            
            app.DataloadedLamp.Color='#008000';
        end

        % Button pushed function: increasecontrastButton
        function increasecontrastButtonPushed(app, event)
            if app.flag==0
                f = msgbox('Please iniate first', 'Error','error');
            else
            montage(reshape(uint16(app.MRI), [size(app.MRI,1) size(app.MRI,2) 1 size(app.MRI,3)]), 'DisplayRange', []);
            set(gca,'clim', [0 100]);
            end
        end

        % Button pushed function: showmontageofdataButton
        function showmontageofdataButtonPushed(app, event)
            if app.flag==0
                f = msgbox('Please iniate first', 'Error','error');
            else
            montage(app.MRI,'DisplayRange',[],'Parent',app.UIAxes);
            end
        end

        % Value changed function: ChoosecutEditField
        function ChoosecutEditFieldValueChanged(app, event)
            value = app.ChoosecutEditField.Value;
            if app.flag==0
                f = msgbox('Please iniate first', 'Error','error');
            else
            if value>60
                f = msgbox('Invalid Value', 'Error','error');
            else
                app.Cut_number=value;
                im = app.MRI(:, :, value); % Pick middle slice for viewing
                max_level = double(max(im(:)));
                imt = imtool(im, [0, max_level]);
                imshow(im,[],'Parent',app.UIAxes)
                app.flag=2;
                
              
%                 app.Image.ImageSource(im)
                
                %plot(app.UIAxes,app.MRI(:,1,1),app.MRI(1,:,1));
            end
            end
        end

        % Button pushed function: DisplayBrainMassButton
        function DisplayBrainMassButtonPushed(app, event)
            if app.flag<=0
                f = msgbox('Please iniate first', 'Error','error');
            else
                mriAdjust = app.MRI;
                % First, crop the portion below the brain mass
                %mriTemp(app.end_p:end,:) = 0; % ignore positions below brain mass
                mriAdjust(mriAdjust <= app.lower) = 0;
                mriAdjust(mriAdjust >= app.upper) = 0;
                mriAdjust(app.end_p:end,:,:) = 0;
                app.MRI_u=mriAdjust;
                mri_cut=mriAdjust(:,:,app.Cut_number);
                imshow(imadjust(mri_cut/max(mri_cut(:))),'Parent',app.UIAxes)
                %imshow(mriTemp,[])
            end
        end

        % Value changed function: EndpositionofbrainmassEditField
        function EndpositionofbrainmassEditFieldValueChanged(app, event)
            end_position = app.EndpositionofbrainmassEditField.Value;
            app.EndpositionofbrainmassEditField.Limits=[0 255];
            app.end_p=end_position;
            
            %imshow(mriTemp,[])
        end

        % Value changed function: LowertheresholdEditField
        function LowertheresholdEditFieldValueChanged(app, event)
            if app.flag==0
                f = msgbox('Please iniate first', 'Error','error');
            else
            value = app.LowertheresholdEditField.Value;
            app.LowertheresholdEditField.Limits=[0 255];
            app.lower=value;
            end
            
            
        end

        % Value changed function: UpperTheresholdEditField
        function UpperTheresholdEditFieldValueChanged(app, event)
            if app.flag==0
                f = msgbox('Please iniate first', 'Error','error');
            else
            value = app.UpperTheresholdEditField.Value;
            app.UpperTheresholdEditField.Limits=[0 255];
            app.upper=value;
            end
            
        end

        % Button pushed function: OpenImageButton
        function OpenImageButtonPushed(app, event)
            if app.flag==0
                f = msgbox('Please iniate first', 'Error','error');
            else
            bw = app.MRI_u > 0;
            nhood = ones([app.X_val,app.Y_val, 3]);
            bw4 = imopen(bw,nhood);
            app.bw_temp=bw4;
            imshow(bw4(:,:,30),'Parent',app.UIAxes);
            end
        end

        % Button pushed function: FindBlobsButton
        function FindBlobsButtonPushed(app, event)
            if app.flag==0
                f = msgbox('Please iniate first', 'Error','error');
            else
                
            L = bwlabeln(app.bw_temp);
            app.L_data=L;
            stats = regionprops(L,'Area','Centroid');
            app.stats_data=stats;
            LL = L(:,:,app.Cut_number)+1;
            cmap = hsv(length(stats));
            cmap = [0 0 0;cmap];
%             rgbplot(cmap)
%             colormap(cmap)
%             colorbar('Ticks',[])
            LL2 = cmap(LL, :);
            LL3 = reshape(LL2, [app.info_data.Rows, app.info_data.Columns, 3]);
            imshow(LL3,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: KeeplargestblobButton
        function KeeplargestblobButtonPushed(app, event)
            if app.flag==0
                f = msgbox('Please iniate first', 'Error','error');
            else
            A = [app.stats_data.Area];
            biggest = find(A == max(A));
            app.MRI_u(app.L_data ~= biggest) = 0;
            imA = app.MRI_u(:, :, app.Cut_number);
            imshow(imA,[],'Parent',app.UIAxes);
            end
        end

        % Button pushed function: GreyandWhitematterSeparationButton
        function GreyandWhitematterSeparationButtonPushed(app, event)
            if app.flag==0
                f = msgbox('Please iniate first', 'Error','error');
            else
            level = thresh_tool(uint16(app.MRI_u(:,:,app.Cut_number)), 'gray');
            mriBrainPartition = uint8(zeros(size(app.MRI_u))); %0=outside brain (head/air)
            mriBrainPartition(app.MRI_u<level & app.MRI_u>0) = 2; %2=gray matter
            mriBrainPartition(app.MRI_u>=level) = 3; %3=white matter
            app.Partition=mriBrainPartition;
            imshow(mriBrainPartition(:,:,app.Cut_number),[0 0 0;0 0 0;.25 .25 1;1 1 1],'Parent',app.UIAxes)
            end
        end

        % Button pushed function: IsosurfaceButton
        function IsosurfaceButtonPushed(app, event)
            if app.flag==0
                f = msgbox('Please iniate first', 'Error','error');
            else
            %tmp = text(128,200,'RENDERING 3D: Please wait','color','r','fontsize',14,...
              %  'horizontalalignment','center');
            % drawnow;
            % shg;
            % downsample
            Ds = imresize(app.Partition,0.25,'nearest');
            Ds = flip(Ds,1);
            Ds = flip(Ds,2);
            Ds = permute(Ds,[3 2 1]);
            voxel_size2 = app.vox([1 3 2]).*[4 1 4];
            white_vol = isosurface(Ds,2.5);
            gray_vol = isosurface(Ds,0.5);
            h = figure('visible','off','outerposition',[0 0 800 600],'renderer','openGL');
            patch(white_vol,'FaceColor','b','EdgeColor','none');
            patch(gray_vol,'FaceColor','y' ,'EdgeColor','none','FaceAlpha',0.5);
            view(45,15); daspect(1./voxel_size2); axis tight;axis off;
            camlight; camlight(-80,-10); lighting phong;
            %delete(tmp);
            movegui(h, 'center');
            set(h,'visible','on');
            end
        end

        % Value changed function: nhoodXEditField
        function nhoodXEditFieldValueChanged(app, event)
            if app.flag==0
                f = msgbox('Please iniate first', 'Error','error');
            else
            value = app.nhoodXEditField.Value;
            app.X_val=value;
            end
        end

        % Value changed function: nhoodYEditField
        function nhoodYEditFieldValueChanged(app, event)
            value = app.nhoodYEditField.Value;
            app.Y_val=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InitiateButton = uibutton(app.UIFigure, 'push');
            app.InitiateButton.ButtonPushedFcn = createCallbackFcn(app, @InitiateButtonPushed, true);
            app.InitiateButton.Text = 'Initiate';
            app.increasecontrastButton = uibutton(app.UIFigure, 'push');
            app.increasecontrastButton.ButtonPushedFcn = createCallbackFcn(app, @increasecontrastButtonPushed, true);
            app.increasecontrastButton.Text = 'increase contrast';
            app.showmontageofdataButton = uibutton(app.UIFigure, 'push');
            app.showmontageofdataButton.ButtonPushedFcn = createCallbackFcn(app, @showmontageofdataButtonPushed, true);
            app.showmontageofdataButton.Text = 'show montage of data';
            app.DataloadedLampLabel = uilabel(app.UIFigure);
            app.DataloadedLampLabel.Text = 'Data loaded';
            app.DataloadedLamp = uilamp(app.UIFigure);
            app.ChoosecutEditFieldLabel = uilabel(app.UIFigure);
            app.ChoosecutEditFieldLabel.Text = 'Choose cut #';
            app.ChoosecutEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChoosecutEditField.ValueChangedFcn = createCallbackFcn(app, @ChoosecutEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'MRI viewer')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.DisplayBrainMassButton = uibutton(app.UIFigure, 'push');
            app.DisplayBrainMassButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayBrainMassButtonPushed, true);
            app.DisplayBrainMassButton.Text = 'Display Brain Mass';
            app.EndpositionofbrainmassEditFieldLabel = uilabel(app.UIFigure);
            app.EndpositionofbrainmassEditFieldLabel.Text = 'End position of brain mass ';
            app.EndpositionofbrainmassEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndpositionofbrainmassEditField.ValueChangedFcn = createCallbackFcn(app, @EndpositionofbrainmassEditFieldValueChanged, true);
            app.LowertheresholdEditFieldLabel = uilabel(app.UIFigure);
            app.LowertheresholdEditFieldLabel.Text = 'Lower thereshold';
            app.LowertheresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowertheresholdEditField.ValueChangedFcn = createCallbackFcn(app, @LowertheresholdEditFieldValueChanged, true);
            app.UpperTheresholdEditFieldLabel = uilabel(app.UIFigure);
            app.UpperTheresholdEditFieldLabel.Text = 'Upper Thereshold';
            app.UpperTheresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperTheresholdEditField.ValueChangedFcn = createCallbackFcn(app, @UpperTheresholdEditFieldValueChanged, true);
            app.OpenImageButton = uibutton(app.UIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.FindBlobsButton = uibutton(app.UIFigure, 'push');
            app.FindBlobsButton.ButtonPushedFcn = createCallbackFcn(app, @FindBlobsButtonPushed, true);
            app.FindBlobsButton.Text = 'Find Blobs';
            app.KeeplargestblobButton = uibutton(app.UIFigure, 'push');
            app.KeeplargestblobButton.ButtonPushedFcn = createCallbackFcn(app, @KeeplargestblobButtonPushed, true);
            app.KeeplargestblobButton.Text = 'Keep largest blob';
            app.GreyandWhitematterSeparationButton = uibutton(app.UIFigure, 'push');
            app.GreyandWhitematterSeparationButton.ButtonPushedFcn = createCallbackFcn(app, @GreyandWhitematterSeparationButtonPushed, true);
            app.GreyandWhitematterSeparationButton.Text = {'Grey and White '; 'matter Separation'};
            app.IsosurfaceButton = uibutton(app.UIFigure, 'push');
            app.IsosurfaceButton.ButtonPushedFcn = createCallbackFcn(app, @IsosurfaceButtonPushed, true);
            app.IsosurfaceButton.Text = 'Iso surface';
            app.nhoodXEditFieldLabel = uilabel(app.UIFigure);
            app.nhoodXEditFieldLabel.Text = 'nhood X';
            app.nhoodXEditField = uieditfield(app.UIFigure, 'numeric');
            app.nhoodXEditField.ValueChangedFcn = createCallbackFcn(app, @nhoodXEditFieldValueChanged, true);
            app.nhoodYEditFieldLabel = uilabel(app.UIFigure);
            app.nhoodYEditFieldLabel.Text = 'nhood Y';
            app.nhoodYEditField = uieditfield(app.UIFigure, 'numeric');
            app.nhoodYEditField.ValueChangedFcn = createCallbackFcn(app, @nhoodYEditFieldValueChanged, true);
            app.PleasespecifymatlabfolderwithDICOMfilesfirstLabel = uilabel(app.UIFigure);
            app.PleasespecifymatlabfolderwithDICOMfilesfirstLabel.Text = 'Please specify matlab folder with DICOM files first';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignment_1618007

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end